classdef AminoacidGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        YourwordEditFieldLabel      matlab.ui.control.Label
        YourwordEditField           matlab.ui.control.EditField
        GenerateaminoacidsequenceButton  matlab.ui.control.Button
        CodonsequenceTextAreaLabel  matlab.ui.control.Label
        CodonsequenceTextArea       matlab.ui.control.TextArea
        Error                       matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateaminoacidsequenceButton
        function GenerateaminoacidsequenceButtonPushed(app, event)
input = string(app.CodonsequenceTextArea.Value{:});
           % if ~exist('M')
                load('codondict_rev.mat',"M");
           % end

errorcond = (isstrprop(input,'alpha')) | (isstrprop(input,'wspace'));
brkcond = 0;
if isempty(input)
    app.Error.Text = 'Enter a valid sequence! No numbers or symbols';
else 
    if ~min(errorcond)
    app.Error.Text = 'Enter a valid sequence! No numbers or symbols';
    else
    app.Error.Text = '';
                
if strcmp(string(app.Switch.Value),'mRNA')
   input = strrep(input,'U','T');
end
upperinput = upper(input);
inputsplit = strsplit(upperinput);

output = cell(1, length(inputsplit));

for k = 1:length(inputsplit)
    if sum(strcmp({'TAA' 'TAG' 'TGA'}, inputsplit{k}))
        output{k} = '';
        break
    else
    if sum(strcmp(M.keys,inputsplit{k}))
   output{k} = M(inputsplit{k});
    else
   app.Error.Text = 'Enter a valid sequence! Codons separated by spaces';
   brkcond = 1;
   break
    end
   end
end

if brkcond~=1
    
codonoutput = strjoin(string(output(~isempty(output))));

app.YourwordEditField.Value = codonoutput;
end

    end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Aminoacids_table.svg';
            app.YourwordEditFieldLabel = uilabel(app.UIFigure);
            app.YourwordEditFieldLabel.Text = 'Your word';
            app.YourwordEditField = uieditfield(app.UIFigure, 'text');
            app.GenerateaminoacidsequenceButton = uibutton(app.UIFigure, 'push');
            app.GenerateaminoacidsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateaminoacidsequenceButtonPushed, true);
            app.GenerateaminoacidsequenceButton.Text = 'Generate amino acid sequence!';
            app.CodonsequenceTextAreaLabel = uilabel(app.UIFigure);
            app.CodonsequenceTextAreaLabel.Text = 'Codon sequence';
            app.CodonsequenceTextArea = uitextarea(app.UIFigure);
            app.Error = uilabel(app.UIFigure);
            app.Error.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'mRNA', 'DNA'};
            app.Switch.Value = 'mRNA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AminoacidGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end