classdef Codongenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        YourwordEditFieldLabel      matlab.ui.control.Label
        YourwordEditField           matlab.ui.control.EditField
        GeneratecodonsButton        matlab.ui.control.Button
        CodonsequenceTextAreaLabel  matlab.ui.control.Label
        CodonsequenceTextArea       matlab.ui.control.TextArea
        Error                       matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratecodonsButton
        function GeneratecodonsButtonPushed(app, event)
input = app.YourwordEditField.Value;
           % if ~exist('M')
                load('codondict.mat',"M");
           % end
            
errorcond = (isstrprop(input,'alpha')) | (isstrprop(input,'wspace'));

if isempty(input) || (~min(errorcond))
    app.Error.Text = 'Enter a valid word! No numbers or symbols';
else
    app.Error.Text = '';
            upperinput = upper(input);

output = cell(1,length(upperinput)+1);


for k = 1:length(upperinput)
   codons = split(M(upperinput(k)),", ");
   output{k} = codons{randi(length(codons))};
end
stopcodon = split(M('Stop'),", ");
output{end} = stopcodon{randi(length(stopcodon))};

codonoutput = strjoin(string(output));
if strcmp(string(app.Switch.Value),'mRNA')
   codonoutput = strrep(codonoutput,'T','U');
end
app.CodonsequenceTextArea.Value = codonoutput;
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Aminoacids_table.svg';
            app.YourwordEditFieldLabel = uilabel(app.UIFigure);
            app.YourwordEditFieldLabel.Text = 'Your word';
            app.YourwordEditField = uieditfield(app.UIFigure, 'text');
            app.GeneratecodonsButton = uibutton(app.UIFigure, 'push');
            app.GeneratecodonsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratecodonsButtonPushed, true);
            app.GeneratecodonsButton.Text = 'Generate codon(s)!';
            app.CodonsequenceTextAreaLabel = uilabel(app.UIFigure);
            app.CodonsequenceTextAreaLabel.Text = 'Codon sequence';
            app.CodonsequenceTextArea = uitextarea(app.UIFigure);
            app.Error = uilabel(app.UIFigure);
            app.Error.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'mRNA', 'DNA'};
            app.Switch.Value = 'mRNA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Codongenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end